classdef LiveScriptExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        RunButton   matlab.ui.control.Button
        durEditField                   matlab.ui.control.NumericEditField
        durEditFieldLabel              matlab.ui.control.Label
        f0Slider                       matlab.ui.control.Slider
        f0SliderLabel                  matlab.ui.control.Label
        ampSlider                      matlab.ui.control.Slider
        ampSliderLabel                 matlab.ui.control.Label
        plotTypeDropDown               matlab.ui.control.DropDown
        plotTypeDropDownLabel          matlab.ui.control.Label
    end

    properties (Access = private)
        amp
        dur
        f0
        plotType
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dur = app.durEditField.Value;
            app.f0 = app.f0Slider.Value;
            app.amp = app.ampSlider.Value;
            app.plotType = app.plotTypeDropDown.Value;
            runScript(app);
        end

        % Execute the converted LiveScript logic
        function runScript(app)
            % Read current UI values into local variables
            dur = app.durEditField.Value;
            f0 = app.f0Slider.Value;
            amp = app.ampSlider.Value;
            plotType = app.plotTypeDropDown.Value;

            % Clear axes before redraw
            cla(app.UIAxes, 'reset');

            fs = 1000;                      % sampling frequency (Hz)
            
            t = (0:1/fs:dur)'; % time axis vector (seconds)
            f = fs*(0:numel(t)-1)/numel(t); % frequency axis vector (Hz)
            
            signal = amp*cos(2*pi*f0*t);
            noise = randn(size(t));
            
            xt = signal+noise;
            Xw = 20*log10(abs(fft(xt,numel(f))));
            
            cm = colororder;
            
            if plotType == "Time Domain"
                plot(app.UIAxes,t,xt,'Color',[0.7, 0.7, 0.7]);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t,signal,'Color',cm(1,:),'LineWidth',1);
                
                title(app.UIAxes, 'Time Domain');
                xlabel(app.UIAxes, 'Time (seconds)');
                ylabel(app.UIAxes, 'Amplitude');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            
                legend(app.UIAxes, {"Signal + Noise", "Signal ONLY"});
            
            elseif plotType == "Frequency Domain"
                plot(app.UIAxes,f,Xw,'Color',[0.6, 0.6, 0.6]);
                hold(app.UIAxes, 'on');
                stem(app.UIAxes,f,20*log10(abs(fft(signal,numel(signal)))),'.-','Color',cm(2,:),'LineWidth',1);
                xlim([0 fs/2]);
                ylim([0 60]);
            
                title(app.UIAxes, 'Frequency (Fourier) Domain');
                xlabel(app.UIAxes, 'Frequency (Hz)');
                ylabel(app.UIAxes, 'Power (dB)');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
            
                legend(app.UIAxes, {"Signal + Noise", "Signal Only"});
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            runScript(app);
        end

    end

    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'LiveScriptExample';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            app.durEditFieldLabel = uilabel(app.UIFigure);
            app.durEditFieldLabel.Text = 'Duration';
            app.durEditField = uieditfield(app.UIFigure, 'numeric');
            app.durEditField.Value = 1;
            app.f0SliderLabel = uilabel(app.UIFigure);
            app.f0SliderLabel.Text = 'f';
            app.f0Slider = uislider(app.UIFigure);
            app.f0Slider.Limits = [1 500];
            app.f0Slider.MajorTicks = linspace(1, 500, min(100+1, 6));
            app.f0Slider.Value = 100;
            app.ampSliderLabel = uilabel(app.UIFigure);
            app.ampSliderLabel.Text = 'amp';
            app.ampSlider = uislider(app.UIFigure);
            app.ampSlider.Limits = [0 1];
            app.ampSlider.MajorTicks = linspace(0, 1, min(10+1, 6));
            app.ampSlider.Value = 0.4;
            app.plotTypeDropDownLabel = uilabel(app.UIFigure);
            app.plotTypeDropDownLabel.Text = 'PlotType';
            app.plotTypeDropDown = uidropdown(app.UIFigure);
            app.plotTypeDropDown.Items = {'Time Domain', 'Frequency Domain'};
            app.plotTypeDropDown.Value = 'Time Domain';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveScriptExample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)
            delete(app.UIFigure)
        end
    end

end  % classdef